classdef filtering_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectWebcamButton      matlab.ui.control.Button
        RewindButton            matlab.ui.control.Button
        PauseButton             matlab.ui.control.Button
        PlayResumeButton        matlab.ui.control.Button
        SelectVideoButton       matlab.ui.control.Button
        ControlPanel            matlab.ui.container.Panel
        KernelPanel             matlab.ui.container.Panel
        SaveChangesButton       matlab.ui.control.Button
        SettingsPanel           matlab.ui.container.Panel
        KernelEnabledLamp       matlab.ui.control.Lamp
        KernelEnabledLampLabel  matlab.ui.control.Label
        VisualizeButton         matlab.ui.control.Button
        ResetImageButton        matlab.ui.control.Button
        ReapplyFilterButton     matlab.ui.control.Button
        AddKernelButton         matlab.ui.control.Button
        RemoveKernelButton      matlab.ui.control.Button
        MoveDownButton          matlab.ui.control.Button
        MoveUpButton            matlab.ui.control.Button
        ToggleOnOffButton       matlab.ui.control.Button
        ApplyFilterButton       matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        SelectImageButton       matlab.ui.control.Button
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mode = 1;
        im_orig;
        im_copy;
        im_edit;

        vid;
        pause_vid = 0;
        vid_frame = 1;

        wc;

        kernel_array = {};
        kernel_options = {'Sobel', 'Gaussian Blur', 'Prewitt', 'Scharr', 'Ridge Detection', 'Sharpen', 'Box Blur', 'Unsharp Mask', 'Motion Blur'};
        list_count = 0;
    end
    
    methods (Access = private)
        
        
        function toggleKernelState(app)
            try
                elem = app.ListBox.Value;
                ind = strcmp(app.ListBox.Items, elem);
                knl = app.kernel_array{ind};
                knl.enabled = ~(knl.enabled);
                toggleUpdateHandler(app, knl);
            catch ME
                disp(ME)
            end
        end

        function toggleUpdateHandler(app, knl)
            try
                txt = 'Kernel Enabled';
                colr = [0,1,0];
                if knl.enabled == 0
                    txt = 'Kernel Disabled';
                    colr = [1,0,0];
                end
                
                app.KernelEnabledLampLabel.Text = txt;
                app.KernelEnabledLamp.Color = colr;
                app.KernelEnabledLampLabel.Visible = 1;
                app.KernelEnabledLamp.Visible = 1;
    
                set(app.KernelEnabledLamp, {'Color', 'Visible'}, {colr, 1});
                set(app.KernelEnabledLampLabel, {'Text', 'Visible'}, {txt, 1});
            catch ME
                disp(ME)
            end
        end

        function results = applyFilterChain(app)
            try
                for i = 1:length(app.kernel_array)
                    knl = app.kernel_array{i};
                    app.im_copy = knl.apply(app.im_copy);
                end
                results = app.im_copy; 
            catch ME
                disp(ME)
            end
        end
        

        function refreshImageCopy(app)
            try
                imshow(uint8(app.im_copy), 'Parent',app.UIAxes_2);
            catch ME
                disp(ME)
            end
        end

        function refreshImageOriginal(app)
            try
                imshow((app.im_orig), 'Parent',app.UIAxes);
            catch ME
                disp(ME)
            end
        end

        function resetImageCopy(app)
            try
                app.im_copy = rgb2gray(app.im_orig);
            catch ME
                disp(ME)
            end
        end

        function addKernel(app,elemName,fig)
            try
                elem = getFilterType(app, elemName);
                app.kernel_array{end+1} = elem;
                
                y = string(app.list_count);
                app.ListBox.Items{end+1} = char(strcat(elemName,{' '},y));
                set(app.ListBox, 'Items', app.ListBox.Items);
    
                app.list_count = app.list_count + 1;
                delete(fig);
            catch ME
                disp(ME)
            end
        end

        function removeKernel(app)
            try
                elem = app.ListBox.Value;
                ind = strcmp(app.ListBox.Items, elem);
                app.ListBox.Items(ind) = [];
                app.kernel_array(ind) = [];
                delete(app.SettingsPanel.Children);
                if isempty(app.ListBox.Items)
                    app.KernelEnabledLamp.Visible = 0;
                    app.KernelEnabledLampLabel.Visible = 0;
                    return;
                end
    
                value = app.ListBox.Value;
                ind = strcmp(app.ListBox.Items, value);
                knl = app.kernel_array{ind};      
                knl.show(app.SettingsPanel);
            catch ME
                    disp(ME)
            end
        end

        function moveUp(app)
            try
                elem = app.ListBox.Value;
                ind = find(strcmp(app.ListBox.Items, elem));
                
                if ind == 1
                    return
                end
                
                l_curr = elem;
                l_up = app.ListBox.Items{ind-1};
    
                k_curr = app.kernel_array{ind};
                k_up = app.kernel_array{ind-1};
    
                app.ListBox.Items{ind-1} = l_curr;
                app.ListBox.Items{ind} = l_up;
    
                app.kernel_array{ind-1} = k_curr;
                app.kernel_array{ind} = k_up;
    
                set(app.ListBox, 'Items', app.ListBox.Items);
            catch ME
                disp(ME)
            end
        end


        function moveDown(app)
            try
                elem = app.ListBox.Value;
                ind = find(strcmp(app.ListBox.Items, elem));
                
                c = size(app.ListBox.Items);
                if ind == c(2)
                    return
                end
                
                l_curr = elem;
                l_down = app.ListBox.Items{ind+1};
    
                k_curr = app.kernel_array{ind};
                k_down = app.kernel_array{ind+1};
    
                app.ListBox.Items{ind+1} = l_curr;
                app.ListBox.Items{ind} = l_down;
    
                app.kernel_array{ind+1} = k_curr;
                app.kernel_array{ind} = k_down;
    
                set(app.ListBox, 'Items', app.ListBox.Items);
            catch ME
                disp(ME)
            end
        end

        function useWebcam(app, wc, fig)
            try
                app.wc = webcam(wc);
                delete(fig);
                showWebcam(app);
            catch ME
                disp(ME)
            end
        end

        function showWebcam(app)
            try
                app.pause_vid = 0;
                j=0;
                while (j<300) && (~app.pause_vid) && (app.mode == 3) 
                    frame = snapshot(app.wc);
                    app.im_orig = frame;
                    app.im_copy = rgb2gray(frame);
    
                    applyFilterChain(app);
                    refreshImageCopy(app);
                    refreshImageOriginal(app);
                    
                    pause(0.15);
                    j = j+1;
                end
            catch ME
                disp(ME)
            end
        end


        function res = getFilterType(~,filter_name)
            try
                switch (lower(filter_name))
                    case 'sobel'
                        x = SobelKernel();
                    case 'gaussian blur'
                        x = GaussianBlurKernel();
                    case 'prewitt'
                        x = PrewittKernel();
                    case 'scharr'
                        x = ScharrKernel();
                    case 'ridge detection'
                        x = RidgeDetectionKernel();
                    case 'emboss'
                        x = EmbossKernel();
                    case 'sharpen'
                        x = SharpenKernel();
                    case 'box blur'
                        x = BoxBlurKernel();
                    case 'motion blur'
                        x = MotionBlurKernel();
                    case 'unsharp mask'
                        x = UnsharpMaskKernel();
                    case 'gaussian difference'
                        x = GaussianDifferenceKernel();
                    otherwise
                        x = [];
                end
                res = x;
            catch ME
                disp(ME)
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath kernelclasses/
        end

        % Button pushed function: SelectImageButton
        function OpenFIleButtonPushed(app, event)
            try
                app.mode = 1;
                app.wc = [];
                app.pause_vid = 1;
    
                f = figure( 'Position', [0 0 0 0], 'ToolBar','none','MenuBar','none'); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file,path] = uigetfile('*.png;*.jpg;*.jpeg;*.tif;*.tiff');
                delete(f); %delete the dummy figure
    
                app.im_orig = imread(strcat(path, file));
                imshow(app.im_orig, 'Parent', app.UIAxes);
    
                app.im_copy = rgb2gray(app.im_orig);
                imshow(app.im_copy, 'Parent', app.UIAxes_2);
            catch ME
                disp(ME)
            end

            
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            try
                resetImageCopy(app);
                applyFilterChain(app);
                refreshImageCopy(app);  
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: ResetImageButton
        function ResetImageButtonPushed(app, event)
            try
                resetImageCopy(app);
                refreshImageCopy(app);
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: ReapplyFilterButton
        function ReapplyFilterButtonPushed(app, event)
            try
                applyFilterChain(app);
                refreshImageCopy(app);
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: AddKernelButton
        function AddKernelButtonPushed(app, event)
            try
                fig = uifigure('Name','Add Kernel');
                w = 241;
                h = 170;
                fig.Position = [540 450 w h];
                fig.WindowStyle = 'modal';
    
                drp = uidropdown(fig);
                drp.Position = [13 110 214 22];
                drp.Items = app.kernel_options;
    
                CancelButton = uibutton(fig, 'push');
                CancelButton.Position = [14 50 100 22];
                CancelButton.Text = 'Cancel';
    
                AddButton = uibutton(fig, 'push');
                AddButton.Position = [126 50 100 22];
                AddButton.Text = 'Add';
                
                AddButton.ButtonPushedFcn = @(btn,event) addKernel(app, drp.Value, fig);
                CancelButton.ButtonPushedFcn = @(btn,event) delete(fig);
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: RemoveKernelButton
        function RemoveKernelButtonPushed(app, event)
            try
            removeKernel(app);
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            try
            moveUp(app);
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: ToggleOnOffButton
        function ToggleOnOffButtonPushed(app, event)
            try
            toggleKernelState(app);
            catch ME
                disp(ME)
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            try
            value = app.ListBox.Value;
            ind = strcmp(app.ListBox.Items, value);
            knl = app.kernel_array{ind};
            
            delete(app.SettingsPanel.Children);
            knl.show(app.SettingsPanel);

            toggleUpdateHandler(app, knl);
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            try
            moveDown(app);
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            try
                value = app.ListBox.Value;
                ind = strcmp(app.ListBox.Items, value);
                knl = app.kernel_array{ind};
    
                knl.updateKernelOptions();
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)
            try
                app.mode = 2;
                delete(app.wc);
                app.wc = [];
                app.pause_vid = 1;
    
                f = figure( 'Position', [0 0 0 0], 'ToolBar','none','MenuBar','none'); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file,path] = uigetfile('*.avi;*.mp4;*.mov');
                delete(f); %delete the dummy figure
                
                app.vid_frame = 1;
                app.pause_vid = 1;
                app.vid = VideoReader(strcat(path, file));
                app.im_orig = read(app.vid, app.vid_frame);
                
                imshow(app.im_orig, 'Parent', app.UIAxes);
    
                app.im_copy = rgb2gray(app.im_orig);
                imshow(app.im_copy, 'Parent', app.UIAxes_2);

            catch ME
                disp(ME)
            end
        end

        % Button pushed function: PlayResumeButton
        function PlayResumeButtonPushed(app, event)
            try
                if(app.mode == 2)
                    app.pause_vid = 0;
                    while hasFrame(app.vid) && ~app.pause_vid
                        frame = read(app.vid, app.vid_frame);
                        app.im_orig = frame;
                        app.im_copy = rgb2gray(frame);
                        applyFilterChain(app);
                        refreshImageCopy(app);
                        refreshImageOriginal(app);
                        app.vid_frame = app.vid_frame + 1;
                        pause(0.1);
                    end
                    if ~hasFrame(app.vid)
                        v = app.vid;
                        v.CurrentTime = 0;
                        app.vid_frame = 1;
                    end
                end
                if(app.mode == 3)
                    app.pause_vid = 0;
                    showWebcam(app);
                end
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            try
            if(app.mode == 2 || app.mode == 3)
                app.pause_vid = 1;
            end
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: RewindButton
        function RewindButtonPushed(app, event)
            try
                if(app.mode == 2)
                    v = app.vid;
                    v.CurrentTime = 0;
                    app.vid_frame = 1;
                    frame = read(app.vid, app.vid_frame);
                    app.im_orig = frame;
                    app.im_copy = rgb2gray(frame);
                    refreshImageCopy(app);
                    refreshImageOriginal(app);
                end
            catch ME
                disp(ME)
            end
        end

        % Button pushed function: SelectWebcamButton
        function SelectWebcamButtonPushed(app, event)
            try
                wcl = webcamlist;
                app.mode = 3;  
                fig = uifigure('Name','Select Webcam');
                w = 241;
                h = 130;
                fig.Position = [540 450 w h];
                fig.WindowStyle = 'modal';
    
                drp = uidropdown(fig);
                drp.Position = [13 80 214 22];
                drp.Items = wcl;
    
                CancelButton = uibutton(fig, 'push');
                CancelButton.Position = [14 32 100 22];
                CancelButton.Text = 'Cancel';
    
                AddButton = uibutton(fig, 'push');
                AddButton.Position = [126 32 100 22];
                AddButton.Text = 'Select';
                
                AddButton.ButtonPushedFcn = @(btn,event) useWebcam(app, drp.Value, fig);
                CancelButton.ButtonPushedFcn = @(btn,event) delete(fig);
            catch ME
                disp(ME)
            end
            
            
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            try
                value = app.ListBox.Value;
                ind = strcmp(app.ListBox.Items, value);
                knl = app.kernel_array{ind};
    
                knl.visualize();
            catch ME
                disp(ME)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFIleButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.ListBox = uilistbox(app.ControlPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ApplyFilterButton = uibutton(app.ControlPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.ToggleOnOffButton = uibutton(app.ControlPanel, 'push');
            app.ToggleOnOffButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleOnOffButtonPushed, true);
            app.ToggleOnOffButton.Text = 'Toggle On/Off';
            app.MoveUpButton = uibutton(app.ControlPanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.ControlPanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.RemoveKernelButton = uibutton(app.ControlPanel, 'push');
            app.RemoveKernelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveKernelButtonPushed, true);
            app.RemoveKernelButton.Text = 'Remove Kernel';
            app.AddKernelButton = uibutton(app.ControlPanel, 'push');
            app.AddKernelButton.ButtonPushedFcn = createCallbackFcn(app, @AddKernelButtonPushed, true);
            app.AddKernelButton.Text = 'Add Kernel';
            app.ReapplyFilterButton = uibutton(app.ControlPanel, 'push');
            app.ReapplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ReapplyFilterButtonPushed, true);
            app.ReapplyFilterButton.Text = 'Reapply Filter';
            app.ResetImageButton = uibutton(app.ControlPanel, 'push');
            app.ResetImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageButtonPushed, true);
            app.ResetImageButton.Text = 'Reset Image';
            app.KernelPanel = uipanel(app.ControlPanel);
            app.KernelPanel.Title = 'Kernel Panel';
            app.VisualizeButton = uibutton(app.KernelPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.KernelEnabledLampLabel = uilabel(app.KernelPanel);
            app.KernelEnabledLampLabel.Visible = 'off';
            app.KernelEnabledLampLabel.Text = 'Kernel Enabled';
            app.KernelEnabledLamp = uilamp(app.KernelPanel);
            app.KernelEnabledLamp.Visible = 'off';
            app.SettingsPanel = uipanel(app.KernelPanel);
            app.SettingsPanel.Title = 'Settings';
            app.SaveChangesButton = uibutton(app.KernelPanel, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.SelectVideoButton = uibutton(app.UIFigure, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Video';
            app.PlayResumeButton = uibutton(app.UIFigure, 'push');
            app.PlayResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PlayResumeButtonPushed, true);
            app.PlayResumeButton.Text = 'Play/Resume';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.RewindButton = uibutton(app.UIFigure, 'push');
            app.RewindButton.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.RewindButton.Text = 'Rewind';
            app.SelectWebcamButton = uibutton(app.UIFigure, 'push');
            app.SelectWebcamButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWebcamButtonPushed, true);
            app.SelectWebcamButton.Text = 'Select Webcam';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtering_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end